classdef ProjectExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ProjectMenu         matlab.ui.container.Menu
        NewProjectMenu      matlab.ui.container.Menu
        ChangeprojectMenu   matlab.ui.container.Menu
        OptionsMenu         matlab.ui.container.Menu
        MesurementsMenu     matlab.ui.container.Menu
        NewmeasuremnetMenu  matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        PTree               matlab.ui.container.Tree
        StatusLine          matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
    end

    
    properties (Access = public)
        AEH AEClassifier; % AEClassifier handle object
        NodeCat struct; %strukltura kategorií jednotlivých stromů        
        PCategory; %Skupina kategorií projektů
        MCategory; %Skupina kategorií měření
        SCategory; %Skupina kategorií zkušebních těles
        MasterFolder char;
        US; %object of users
        PR; %projects obj
        UserID; %current user id
        CM;
        PNodeSelected;
    end
    
    properties (Access = private)
        SOPT; % Object for UIwindow of set options
    end
    
    methods (Access = private)
        
        %will load user options and save current user setings
        function LoadUserOptions(app)
            app.US=Users(app);
            app.US=CheckUser(app.US);
            SaveUserOptions(app.US);
        end
    end
    
    methods (Access = public)
        
        %will create new projec
        
        %will load all projects and its measuremnts, also can be used for
        %updating the list of projects on demand (creation, deletion)
        function LoadProjects(app)
            app.PR=ProjectOverView(app.US.UserOptions(app.UserID).SandBoxFolder);
            FillTree(app.PR,app.PTree);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='Project explorer 1.0';
            app.StatusLine.Text='-ready for work-';
            app.MasterFolder=strrep(which('ProjectExplorer'),'ProjectExplorer.mlapp','');
            LoadUserOptions(app);
            LoadProjects(app);
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            app.UIFigure.Visible='off';
            NewProject=EditProject(app);
            
        end

        % Callback function
        function NewMeasurementMenuSelected(app, event)
            
            OpenMeasuremntsCount=size(app.MCategory,1);
            app.MCategory{OpenMeasuremntsCount+1}=uitreenode(app.PCategory{1},'Text','Matlab','NodeData',[]);            
            app.AEH=AEClassifier(2);
            LoadMeasurement(app.AEH);
            app.StatusLine.Text=app.AEH.CL;
            pause(5);
            app.StatusLine.Text='-ready for work-';
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            app.UIFigure.Visible='off';
            app.SOPT=SetOptions(app);
        end

        % Selection changed function: PTree
        function ProjectSelectLMB(app, event)
            app.PNodeSelected = app.PTree.SelectedNodes;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewProjectMenu = uimenu(app.ProjectMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.ChangeprojectMenu = uimenu(app.ProjectMenu);
            app.ChangeprojectMenu.Text = 'Change project';
            app.OptionsMenu = uimenu(app.ProjectMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Text = 'Options';
            app.MesurementsMenu = uimenu(app.UIFigure);
            app.MesurementsMenu.Text = 'Mesurements';
            app.NewmeasuremnetMenu = uimenu(app.MesurementsMenu);
            app.NewmeasuremnetMenu.Text = 'New measuremnet';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PTree = uitree(app.GridLayout);
            app.PTree.Multiselect = 'on';
            app.PTree.SelectionChangedFcn = createCallbackFcn(app, @ProjectSelectLMB, true);
            app.StatusLine = uilabel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end